classdef exploreSimulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    properties (Access = private)
        TileLayout matlab.graphics.layout.TiledChartLayout % Added property for TileLayout
        SecondLayout matlab.graphics.layout.TiledChartLayout % Added property for TileLayout
        t
        XSlider matlab.ui.control.Slider
        YSlider matlab.ui.control.Slider
        RightPanel matlab.ui.container.Panel
        PlotTitle matlab.ui.control.Label  % Label used to display the overall title above the plot panel
        XSliderValueLabel matlab.ui.control.Label
        YSliderValueLabel matlab.ui.control.Label
        Button1
        Button2
        Button3
        PlotSelector matlab.ui.control.DropDown % Drop-down menu to select plot type
    end
    methods (Access = private)

        % Callback function to update the horizontal slider value label
        function updateSliderValueLabel(app, slider)
            app.XSliderValueLabel.Text = num2str(slider.Value);  % Update the label text with the slider value
        end

        % Callback function to update the vertical slider value label
        function updateVerticalSliderValueLabel(app, slider)
            app.YSliderValueLabel.Text = num2str(slider.Value);  % Update the label text with the slider value
        end
        
        function Button1Pushed(app, event,sim)
            xVal = floor(app.XSlider.Value);
            yVal = floor(app.YSlider.Value);

            % Display the slider values in the MATLAB console
            fprintf('X slider value: %.2f\n', xVal);
            fprintf('Y slider value: %.2f\n', yVal);

            % You can also do something else with them, like displaying them in the app, etc.

            figure
            plotWatercolumn(sim, xVal, 'depthMax', 200);
        end
        function Button2Pushed(app, event,sim)
            xVal = floor(app.XSlider.Value);
            yVal = floor(app.YSlider.Value);

            % Display the slider values in the MATLAB console
            fprintf('X slider value: %.2f\n', xVal);
            fprintf('Y slider value: %.2f\n', yVal);

            % You can also do something else with them, like displaying them in the app, etc.
            Bdepth = sum( sim.B(xVal,:,:),3 );
            iDepth = find(Bdepth==max(Bdepth));
            disp(iDepth);
            disp(class(iDepth))
            %figure
            [~, app.SecondLayout] = plotSizeSpectrumTest(sim, xVal, iDepth, [], [], app.RightPanel);

        end
        function Button3Pushed(app, event,sim)
            xVal = floor(app.XSlider.Value);
            yVal = floor(app.YSlider.Value);

            % Display the slider values in the MATLAB console
            fprintf('X slider value: %.2f\n', xVal);
            fprintf('Y slider value: %.2f\n', yVal);

            % You can also do something else with them, like displaying them in the app, etc.
            Bdepth = sum(sum( sim.B(:,:,:),3 ),1);
            iDepth = find(Bdepth==max(Bdepth));
            figure
            plotSizespectrumTime(sim,iDepth);
        end
        function onResize(app)
            % Get current figure size
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            figHeight = figPos(4);

            % Left layout
            margin = 20;
            tileW = 0.40 * figWidth;
            tileX = 0.05 * figWidth + 80;
            tileY = 0.5 * figHeight;
            tileH = 0.4 * figHeight;

            % Right panel
            panelW = 0.40 * figWidth;
            panelX = tileX + tileW + margin;
            panelY = tileY;
            panelH = tileH;

            % Update plot layout
            app.TileLayout.Position = [tileX / figWidth, tileY / figHeight, ...
                tileW / figWidth, tileH / figHeight];

            % Update X Slider and label
            app.XSlider.Position = [tileX, tileY - 50, tileW, 3];
            app.XSliderValueLabel.Position = [tileX + tileW + 10, tileY - 40, 50, 20];

            % Update Y Slider and label
            sliderX = tileX - 110;
            app.YSlider.Position = [sliderX, tileY, 3, tileH];
            app.YSliderValueLabel.Position = [sliderX - 10, tileY + tileH + 10, 50, 20];

            % Update Buttons layout
            buttonY = tileY - 120;
            app.Button1.Position = [tileX, buttonY, 100, 22];
            app.Button2.Position = [tileX + 120, buttonY, 100, 22];
            app.Button3.Position = [tileX + 240, buttonY, 100, 22];
            % Update PlotSelector Layout
            app.PlotSelector.Position = [figWidth - (figWidth/2), figHeight - 50, 150, 22];
            % Update left layout
            app.RightPanel.Position = [panelX / figWidth, panelY / figHeight, ...
                    panelW / figWidth, panelH / figHeight];
        end
        function updatePlot(app, event,sim)
            % Get the selected plot type from the drop-down menu
            plotType = app.PlotSelector.Value;
            disp(plotType)
            % Call the plot function with necessary arguments
            app.TileLayout = plotWatercolumnTimeTest(sim, 'depthMax', 200, plotType, app.UIFigure);
            onResize(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sim, options)
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure.SizeChangedFcn = @(src, event) onResize(app);
            % Window dimensions
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            figHeight = figPos(4);
            disp(sim.p.nameGroup)
            disp(sim.p.nameNutrientsLong)
            % TileLayout dimensions and position in pixels
            tileW = 0.45 * figWidth;   % ~514 px
            tileH = 0.6 * figHeight;   % ~370 px
            tileX = 0.05 * figWidth + 50;   % Slightly shifted to the right (~50 px)
            tileY = 0.3 * figHeight;   % ~185 px

            % Create a panel on the right for future content
            app.RightPanel = uipanel(app.UIFigure);
            app.RightPanel.Title = 'Details';
            app.RightPanel.BackgroundColor = [1 1 1];  % White background
            
            % Set up the plot selector drop-down menu
            app.PlotSelector = uidropdown(app.UIFigure);
            
            app.PlotSelector.Items = [sim.p.nameGroup, sim.p.nameNutrientsLong,'Biomass'];
            %app.PlotSelector.Items = {'Biomass','Nitrogen', 'Silicate', 'DOC'};  % List of plot types
            app.PlotSelector.Position = [figWidth - 150, figHeight - 50, 150, 22];
            app.PlotSelector.ValueChangedFcn = @(src, event) updatePlot(app, event,sim);  % Callback function

            % 2. Set this panel as the parent
            disp(app.PlotSelector.Value)
            app.TileLayout = plotWatercolumnTimeTest(sim, 'depthMax', 200,app.PlotSelector.Value ,app.UIFigure);
            app.TileLayout.Position = [tileX / figWidth, tileY / figHeight, ...
                                       tileW / figWidth, tileH / figHeight];
            % Store the time
            app.t = sim.t;

            % ---- Horizontal Slider (X): below the plot ----
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Position = [tileX, tileY - 40, tileW, 3];  % Position below the TileLayout
            app.XSlider.Limits = [min(sim.t(:)), max(sim.t(:))];  % Based on the real time range
            app.XSlider.Value = app.XSlider.Limits(1);

            % Create a label to display the slider value
            app.XSliderValueLabel = uilabel(app.UIFigure);
            app.XSliderValueLabel.Position = [tileX + tileW + 10, tileY - 40, 50, 20];  % Just beside the slider
            app.XSliderValueLabel.Text = num2str(app.XSlider.Value);  % Set initial value

            % Update the label dynamically as the slider moves
            app.XSlider.ValueChangedFcn = @(src, event) updateSliderValueLabel(app, src);

            % ---- Vertical Slider (Y): to the left of the plot ----
            sliderWidth = 3;
            app.YSlider = uislider(app.UIFigure, 'Orientation', 'vertical');

            sliderX = tileX - 40;  % Slightly to the left of the TileLayout with margin
            sliderY = tileY;
            app.YSlider.Position = [sliderX - 50, sliderY, sliderWidth, tileH];

            % Define the Y slider range from -710 to 0
            app.YSlider.Limits = [-710, 0];  % Adjust the slider limits
            app.YSlider.Value = -710;        % Initial value

            % Create a label to display the vertical slider value
            app.YSliderValueLabel = uilabel(app.UIFigure);
            app.YSliderValueLabel.Position = [sliderX - 30, sliderY + tileH + 10, 50, 20];  % Just above the vertical slider
            app.YSliderValueLabel.Text = num2str(app.YSlider.Value);  % Set initial value

            % Update the label dynamically as the vertical slider moves
            app.YSlider.ValueChangedFcn = @(src, event) updateVerticalSliderValueLabel(app, src);

            % ---- Add a "NButton" button ----
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.Position = [tileX, tileY - 120, 100, 22];  % Position x, y, width, height
            app.Button1.Text = 'Plot Water Column';

            % Assign the callback
            app.Button1.ButtonPushedFcn = @(btn, event) Button1Pushed(app, event,sim);

            % ---- Add a "Button2" button ----
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Position = [tileX+120, tileY - 120, 100, 22];  % Position x, y, width, height
            app.Button2.Text = 'Plot Size Spectrum';

            % Assign the callback
            app.Button2.ButtonPushedFcn = @(btn, event) Button2Pushed(app, event,sim);
            % ---- Add a "Button3" button ----
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.Position = [tileX+240, tileY - 120, 100, 22];  % Position x, y, width, height
            app.Button3.Text = 'Plot Size Spectrum Time';

            % Assign the callback
            app.Button3.ButtonPushedFcn = @(btn, event) Button3Pushed(app, event,sim);
            disp("before resize")
            
            onResize(app);
        end

        % Callback function
        function ButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exploreSimulations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end